classdef ControlUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        ConnectBoardButton      matlab.ui.control.Button
        RotateCWButton          matlab.ui.control.Button
        RotateCCWButton         matlab.ui.control.Button
        CloseBoardButton        matlab.ui.control.Button
        StartRobotButton        matlab.ui.control.Button
        XEditFieldLabel         matlab.ui.control.Label
        XEditField              matlab.ui.control.NumericEditField
        YEditFieldLabel         matlab.ui.control.Label
        YEditField              matlab.ui.control.NumericEditField
        MoveCartesianButton     matlab.ui.control.Button
        ShutDownRobotButton     matlab.ui.control.Button
        YawEditFieldLabel       matlab.ui.control.Label
        YawEditField            matlab.ui.control.NumericEditField
        DistanceEditFieldLabel  matlab.ui.control.Label
        DistanceEditField       matlab.ui.control.NumericEditField
        MoveYawDistanceButton   matlab.ui.control.Button
        GaugeLabel              matlab.ui.control.Label
        Gauge                   matlab.ui.control.Gauge
        Stepper2CWButton        matlab.ui.control.Button
        Stepper2CCWButton       matlab.ui.control.Button
        SetupVariablesButton    matlab.ui.control.Button
    end

    
    properties (Access = private)
        board; % Description
        robot;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ConnectBoardButton
        function ConnectBoardButtonPushed(app, event)
            app.board = serial('COM6','BaudRate',9600);   
            fopen(app.board);
            disp('Connect Arduino successfully');
        end

        % Button pushed function: RotateCWButton
        function RotateCWButtonPushed(app, event)
            fprintf(app.board,1);                
        end

        % Button pushed function: RotateCCWButton
        function RotateCCWButtonPushed(app, event)
            fprintf(app.board,2);
        end

        % Button pushed function: CloseBoardButton
        function CloseBoardButtonPushed(app, event)
           try 
               fclose(app.board);
               delete(app.board);
           catch 
               disp("Unable to close the board");
           end
        end

        % Button pushed function: StartRobotButton
        function StartRobotButtonPushed(app, event)
            app.robot = iRobotCreate(3);
        end

        % Button pushed function: MoveCartesianButton
        function MoveCartesianButtonPushed(app, event)
            x = app.XEditField.Value;
            y = app.YEditField.Value;
            app.robot.MoveXY(x,y);
        end

        % Button pushed function: ShutDownRobotButton
        function ShutDownRobotButtonPushed(app, event)
            try
                delete(app.robot);
            catch 
                disp("Shut down fail");
            end
        end

        % Button pushed function: MoveYawDistanceButton
        function MoveYawDistanceButtonPushed(app, event)
            yaw = app.YawEditField.Value;
            distance = app.DistanceEditField.Value;
            app.robot.MoveYawDist(yaw,distance);
        end

        % Button pushed function: Stepper2CWButton
        function Stepper2CWButtonPushed(app, event)
            fprintf(app.board,3);
        end

        % Button pushed function: Stepper2CCWButton
        function Stepper2CCWButtonPushed(app, event)
            fprintf(app.board,4);
        end

        % Button pushed function: SetupVariablesButton
        function SetupVariablesButtonPushed(app, event)
            DeclareVariable;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.ConnectBoardButton = uibutton(app.UIFigure, 'push');
            app.ConnectBoardButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectBoardButtonPushed, true);
            app.ConnectBoardButton.Text = 'Connect Board';
            app.RotateCWButton = uibutton(app.UIFigure, 'push');
            app.RotateCWButton.ButtonPushedFcn = createCallbackFcn(app, @RotateCWButtonPushed, true);
            app.RotateCWButton.Text = 'Rotate CW';
            app.RotateCCWButton = uibutton(app.UIFigure, 'push');
            app.RotateCCWButton.ButtonPushedFcn = createCallbackFcn(app, @RotateCCWButtonPushed, true);
            app.RotateCCWButton.Text = 'Rotate CCW';
            app.CloseBoardButton = uibutton(app.UIFigure, 'push');
            app.CloseBoardButton.ButtonPushedFcn = createCallbackFcn(app, @CloseBoardButtonPushed, true);
            app.CloseBoardButton.Text = 'Close Board';
            app.StartRobotButton = uibutton(app.UIFigure, 'push');
            app.StartRobotButton.ButtonPushedFcn = createCallbackFcn(app, @StartRobotButtonPushed, true);
            app.StartRobotButton.Text = 'Start Robot';
            app.XEditFieldLabel = uilabel(app.UIFigure);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.UIFigure, 'numeric');
            app.YEditFieldLabel = uilabel(app.UIFigure);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.UIFigure, 'numeric');
            app.MoveCartesianButton = uibutton(app.UIFigure, 'push');
            app.MoveCartesianButton.ButtonPushedFcn = createCallbackFcn(app, @MoveCartesianButtonPushed, true);
            app.MoveCartesianButton.Text = 'Move Cartesian';
            app.ShutDownRobotButton = uibutton(app.UIFigure, 'push');
            app.ShutDownRobotButton.ButtonPushedFcn = createCallbackFcn(app, @ShutDownRobotButtonPushed, true);
            app.ShutDownRobotButton.Text = {'Shut Down Robot'; ''};
            app.YawEditFieldLabel = uilabel(app.UIFigure);
            app.YawEditFieldLabel.Text = 'Yaw';
            app.YawEditField = uieditfield(app.UIFigure, 'numeric');
            app.DistanceEditFieldLabel = uilabel(app.UIFigure);
            app.DistanceEditFieldLabel.Text = 'Distance';
            app.DistanceEditField = uieditfield(app.UIFigure, 'numeric');
            app.MoveYawDistanceButton = uibutton(app.UIFigure, 'push');
            app.MoveYawDistanceButton.ButtonPushedFcn = createCallbackFcn(app, @MoveYawDistanceButtonPushed, true);
            app.MoveYawDistanceButton.Text = 'Move Yaw/Distance';
            app.GaugeLabel = uilabel(app.UIFigure);
            app.GaugeLabel.Text = 'Gauge';
            app.Gauge = uigauge(app.UIFigure, 'circular');
            app.Gauge.Limits = [-180 180];
            app.Stepper2CWButton = uibutton(app.UIFigure, 'push');
            app.Stepper2CWButton.ButtonPushedFcn = createCallbackFcn(app, @Stepper2CWButtonPushed, true);
            app.Stepper2CWButton.Text = 'Stepper 2 CW';
            app.Stepper2CCWButton = uibutton(app.UIFigure, 'push');
            app.Stepper2CCWButton.ButtonPushedFcn = createCallbackFcn(app, @Stepper2CCWButtonPushed, true);
            app.Stepper2CCWButton.Text = 'Stepper 2 CCW';
            app.SetupVariablesButton = uibutton(app.UIFigure, 'push');
            app.SetupVariablesButton.ButtonPushedFcn = createCallbackFcn(app, @SetupVariablesButtonPushed, true);
            app.SetupVariablesButton.Text = 'Setup Variables';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ControlUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end